classdef MainMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        tab1                    matlab.ui.container.Tab
        TabGroup_2              matlab.ui.container.TabGroup
        PCM                     matlab.ui.container.Tab
        DM                      matlab.ui.container.Tab
        DeltaModulationDMLabel  matlab.ui.control.Label
        DeltaLabel              matlab.ui.control.Label
        EditField               matlab.ui.control.NumericEditField
        SignalRangeLabel        matlab.ui.control.Label
        EditField_2             matlab.ui.control.NumericEditField
        toLabel                 matlab.ui.control.Label
        EditField_3             matlab.ui.control.NumericEditField
        RangeGapLabel           matlab.ui.control.Label
        EditField_4             matlab.ui.control.NumericEditField
        DropDown                matlab.ui.control.DropDown
        FunctionLabel           matlab.ui.control.Label
        GenerateButton          matlab.ui.control.Button
        EditField_5             matlab.ui.control.NumericEditField
        AmplitudeLabel          matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
        Button                  matlab.ui.control.Button
        ComparewithPCMButton    matlab.ui.control.Button
        FrequencyLabel          matlab.ui.control.Label
        EditField_6             matlab.ui.control.NumericEditField
        SamplingRateLabel       matlab.ui.control.Label
        EditField_7             matlab.ui.control.NumericEditField
        CompareButton           matlab.ui.control.Button
        Lamp_2                  matlab.ui.control.Lamp
        tab2                    matlab.ui.container.Tab
        TabGroup_3              matlab.ui.container.TabGroup
        PCM_2                   matlab.ui.container.Tab
        DM_2                    matlab.ui.container.Tab
        Lamp                    matlab.ui.control.Lamp
    end

    properties (Access = private)
        OriginalAxesPosition  % Stores original UIAxes position
        IsExpanded = false    % Tracks whether UIAxes is expanded
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center the app on screen
            screenSize = get(0, 'ScreenSize');
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            posX = (screenSize(3) - appWidth) / 2;
            posY = (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [posX, posY];
            
            % Compare functionality hidden
            app.FrequencyLabel.Visible = 'off';
            app.EditField_6.Visible = 'off';
            app.SamplingRateLabel.Visible = 'off';
            app.EditField_7.Visible = 'off';
            app.CompareButton.Visible = 'off';


            % Lamp positions on startup
            app.Lamp.Position = [112 704 10 10];
            app.Lamp_2.Position = [41, 679, 10, 10];
            
            % UI Axes initial position
            app.OriginalAxesPosition = app.UIAxes.Position;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.IsExpanded
                app.ComparewithPCMButton.Visible = 'on';
                app.UIAxes.Position = app.OriginalAxesPosition;
                app.IsExpanded = false;
                app.Button.Position = [976 577 30 30];
                app.UIAxes.FontSize = 11;
            else
                % Compare functionality hidden
                app.ComparewithPCMButton.Visible = 'off';
                app.FrequencyLabel.Visible = 'off';
                app.EditField_6.Visible = 'off';
                app.SamplingRateLabel.Visible = 'off';
                app.EditField_7.Visible = 'off';
                app.CompareButton.Visible = 'off';
                app.UIAxes.Position = [50 50 980 600];  % New expanded size
                app.IsExpanded = true;
                app.Button.Position = [1000 619 30 30];
                app.UIAxes.FontSize = 16;
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.UIAxes.XTickMode = 'auto';
            app.UIAxes.YTickMode = 'auto';
            app.UIAxes.XTickLabelMode = 'auto';
            app.UIAxes.YTickLabelMode = 'auto';
            
            
            % Get user inputs
            delta = app.EditField.Value;
            range_min = app.EditField_2.Value;
            range_max = app.EditField_3.Value;
            gap = app.EditField_4.Value;
            amplitude = app.EditField_5.Value;
            func_type = app.DropDown.Value;
        
            % Generate time vector
            t = range_min:gap:range_max;
        
            % Generate signal based on selected function
            switch func_type
                case 'sin(x)'
                    x = abs(amplitude * sin(t));
                case 'cos(x)'
                    x = abs(amplitude * cos(t));
            end
        
            % Delta modulation
            x_dig = zeros(1, length(t));
            x0 = zeros(1, length(t));
            x0(1) = 0;
        
            for i = 2:length(t)
                if x(i) > x0(i-1)
                    x_dig(i) = 1;
                    x0(i) = x0(i-1) + delta;
                else
                    x_dig(i) = 0;
                    x0(i) = x0(i-1) - delta;
                end
            end
        
            % Plot on UIAxes
            cla(app.UIAxes); % Clear previous plots
            plot(app.UIAxes, t, x, 'r', 'LineWidth', 1.5);
            hold(app.UIAxes, 'on');
            stairs(app.UIAxes, t, x0, 'b', 'LineWidth', 1.5);
            legend(app.UIAxes, 'Original Signal', 'Delta Modulated Signal');
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Delta Modulation');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');
            
            xlim(app.UIAxes, [range_min range_max]);
            ylim(app.UIAxes, [0 amplitude + delta]);  % Adjust as needed
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            if selectedTab == app.tab1
                % Change to green and move to top-left
                app.Lamp.Position = [112 704 10 10];
        
            elseif selectedTab == app.tab2
                % Change to red and move to bottom-right
                app.Lamp.Position = [243 704 10 10];
            end
        end

        % Selection change function: TabGroup_2
        function TabGroup_2SelectionChanged(app, event)
            selectedTab = app.TabGroup_2.SelectedTab;
            
            if selectedTab == app.PCM
                % Change to green and move to top-left
                app.Lamp_2.Position = [41, 679, 10, 10];
        
            elseif selectedTab == app.DM
                % Change to red and move to bottom-right
                app.Lamp_2.Position = [87, 679, 10, 10];
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            disp('App is closing...');
            delete(app);
        end

        % Button pushed function: ComparewithPCMButton
        function ComparewithPCMButtonPushed(app, event)
                app.ComparewithPCMButton.Visible = 'off';
                app.FrequencyLabel.Visible = 'on';
                app.EditField_6.Visible = 'on';
                app.SamplingRateLabel.Visible = 'on';
                app.EditField_7.Visible = 'on';
                app.CompareButton.Visible = 'on';
        end

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)
            app.UIAxes.XTickMode = 'auto';
            app.UIAxes.YTickMode = 'auto';
            app.UIAxes.XTickLabelMode = 'auto';
            app.UIAxes.YTickLabelMode = 'auto';
            
            
            % Get user inputs
            delta = app.EditField.Value;
            range_min = app.EditField_2.Value;
            range_max = app.EditField_3.Value;
            gap = app.EditField_4.Value;
            amplitude = app.EditField_5.Value;
            func_type = app.DropDown.Value;
   
        
            % Generate time vector
            t = range_min:gap:range_max;
        
            % Generate signal based on selected function
            switch func_type
                case 'sin(x)'
                    x = abs(amplitude * sin(t));
                case 'cos(x)'
                    x = abs(amplitude * cos(t));
            end
        
            % Delta modulation
            x_dig = zeros(1, length(t));
            x0 = zeros(1, length(t));
            x0(1) = 0;
        
            for i = 2:length(t)
                if x(i) > x0(i-1)
                    x_dig(i) = 1;
                    x0(i) = x0(i-1) + delta;
                else
                    x_dig(i) = 0;
                    x0(i) = x0(i-1) - delta;
                end
            end
        
            % Plot on UIAxes
            cla(app.UIAxes); % Clear previous plots
            plot(app.UIAxes, t, x, 'r', 'LineWidth', 1.5);
            hold(app.UIAxes, 'on');
            stairs(app.UIAxes, t, x0, 'b', 'LineWidth', 1.5);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Delta Modulation');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'on');
            
            % User input for PCM
            freq = app.EditField_6.Value;
            sampRate = app.EditField_7.Value;
            
            % PCM
            q = freq/2^(sampRate-1);
            x0 = fix(x/q);
            y1 = x0*q;
            
            plot(app.UIAxes, t, y1, 'g', 'LineWidth', 1.5);
            legend(app.UIAxes, 'Original Signal', 'Delta Modulated Signal', 'PCM Signal');
            hold(app.UIAxes, 'on');
            
            xlim(app.UIAxes, [range_min range_max]);
            ylim(app.UIAxes, [0 amplitude + delta]);  % Adjust as needed
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.tab1 = uitab(app.TabGroup);
            app.tab1.Title = 'Signal Conversion';
            app.TabGroup_2 = uitabgroup(app.tab1);
            app.TabGroup_2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup_2SelectionChanged, true);
            app.PCM = uitab(app.TabGroup_2);
            app.PCM.Title = 'PCM';
            app.DM = uitab(app.TabGroup_2);
            app.DM.Title = 'DM';
            app.DeltaModulationDMLabel = uilabel(app.DM);
            app.DeltaModulationDMLabel.Text = 'Delta Modulation (DM)';
            app.DeltaLabel = uilabel(app.DM);
            app.DeltaLabel.Text = 'Delta:';
            app.EditField = uieditfield(app.DM, 'numeric');
            app.EditField.Value = 0.2;
            app.SignalRangeLabel = uilabel(app.DM);
            app.SignalRangeLabel.Text = 'Signal Range:';
            app.EditField_2 = uieditfield(app.DM, 'numeric');
            app.toLabel = uilabel(app.DM);
            app.toLabel.Text = 'to';
            app.EditField_3 = uieditfield(app.DM, 'numeric');
            app.EditField_3.Value = 10;
            app.RangeGapLabel = uilabel(app.DM);
            app.RangeGapLabel.Text = 'Range Gap:';
            app.EditField_4 = uieditfield(app.DM, 'numeric');
            app.EditField_4.Value = 0.1;
            app.DropDown = uidropdown(app.DM);
            app.DropDown.Items = {'sin(x)', 'cos(x)'};
            app.DropDown.Value = 'sin(x)';
            app.FunctionLabel = uilabel(app.DM);
            app.FunctionLabel.Text = 'Function:';
            app.GenerateButton = uibutton(app.DM, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.EditField_5 = uieditfield(app.DM, 'numeric');
            app.EditField_5.Value = 5;
            app.AmplitudeLabel = uilabel(app.DM);
            app.AmplitudeLabel.Text = 'Amplitude:';
            app.UIAxes = uiaxes(app.DM);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.YLim = [0 1];
            app.UIAxes.YLimMode = 'manual';
            app.UIAxes.CLim = [0 1];
            app.UIAxes.CLimMode = 'manual';
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Button = uibutton(app.DM, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'icon-expand.png';
            app.Button.Text = '';
            app.ComparewithPCMButton = uibutton(app.DM, 'push');
            app.ComparewithPCMButton.ButtonPushedFcn = createCallbackFcn(app, @ComparewithPCMButtonPushed, true);
            app.ComparewithPCMButton.Text = 'Compare with PCM';
            app.FrequencyLabel = uilabel(app.DM);
            app.FrequencyLabel.Text = 'Frequency:';
            app.EditField_6 = uieditfield(app.DM, 'numeric');
            app.EditField_6.Value = 6;
            app.SamplingRateLabel = uilabel(app.DM);
            app.SamplingRateLabel.Text = 'Sampling Rate:';
            app.EditField_7 = uieditfield(app.DM, 'numeric');
            app.EditField_7.Value = 3;
            app.CompareButton = uibutton(app.DM, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Text = 'Compare';
            app.Lamp_2 = uilamp(app.tab1);
            app.tab2 = uitab(app.TabGroup);
            app.tab2.Title = 'Encoding/Decoding';
            app.TabGroup_3 = uitabgroup(app.tab2);
            app.PCM_2 = uitab(app.TabGroup_3);
            app.PCM_2.Title = 'PCM';
            app.DM_2 = uitab(app.TabGroup_3);
            app.DM_2.Title = 'DM';
            app.Lamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = MainMenu

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end